classdef swjv0_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        SerialSettingsTab      matlab.ui.container.Tab
        SendButton             matlab.ui.control.Button
        ClearButton            matlab.ui.control.Button
        TerminalTextArea       matlab.ui.control.TextArea
        TerminalTextAreaLabel  matlab.ui.control.Label
        ConnectButton          matlab.ui.control.StateButton
        BaudRateDropDown       matlab.ui.control.DropDown
        BaudRateDropDownLabel  matlab.ui.control.Label
        SerialPortsDropDown    matlab.ui.control.DropDown
        Label                  matlab.ui.control.Label
        Tab2                   matlab.ui.container.Tab
    end


    properties (Access = private)

    end

    properties (Access = public)
        serial_handle
        User_Data
        rt_data
    end

    methods (Access = public)


    end

    methods (Access = private)

        %% update terminal
        function update_terminal(app,newtext)
            text = app.TerminalTextArea.Value;
            text{end+1} = newtext;
            app.TerminalTextArea.Value = text;
        end
        %% serial data handler
        function serial_data_handler(app)
            tmp_data = app.User_Data;
            switch tmp_data(1:3)
                case "#01" % Handshaske from slave.
                    update_terminal(app,'Handshaking succeed.');

                case "#02" % Terminal request from slave.
                    update_terminal(app,tmp_data(4:end));

                case "#03" % Parameter uploading from slave.

                case "#04" % Real-time data stream from slave.
                    rt_data_handler(app,tmp_data(4:end));

                otherwise % communication error
                    update_terminal(app,'Serial communication error.');
            end


        end
        %% real-time data handler
        function rt_data_handler(app,data)

            app.rt_data.size = str2num(data(1:4)); % size of the data stream.
            app.rt_data.data = str2num(data(5:end)); % convert data stream.
            % check the data stream size.
            if ~isequal(app.rt_data.size,size(app.rt_data.data))
                update_terminal(app,'Real-time data stream size error.');
                return;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialPortsDropDown.Items = serialportlist("available");

        end

        % Callback function
        function SendButtonPushed(app, event)
            writeline(app.serial_handle,"hello")
        end

        % Drop down opening function: SerialPortsDropDown
        function SerialPortsDropDownOpening(app, event)
            app.SerialPortsDropDown.Items = serialportlist("available");
        end

        % Value changed function: ConnectButton
        function ConnectButtonValueChanged(app, event)
            value = app.ConnectButton.Value;
            switch value
                case 1 % try to connect serial.
                    try
                        % creat serial handle.
                        app.serial_handle = serialport(...
                            app.SerialPortsDropDown.Value,...
                            str2double(app.BaudRateDropDown.Value));
                        % Set the terminator
                        configureTerminator(app.serial_handle,"CR/LF");
                        %configure callback function.
                        configureCallback(app.serial_handle,"terminator",@readSerialData)
                        app.ConnectButton.Text = 'Connected';
                        new_text = ['Connected to serial port: ',app.SerialPortsDropDown.Value];
                        update_terminal(app,new_text);
                    catch % connection failed.
                        delete(app.serial_handle);
                        new_text = ['Failed to connect to serial port: ',app.SerialPortsDropDown.Value];
                        update_terminal(app,new_text);
                        app.ConnectButton.Value = 0;
                        app.ConnectButton.Text = 'Connect';
                    end
                case 0 % try to disconnect serial
                    delete(app.serial_handle);
                    app.ConnectButton.Text = "Connect";
                    new_text = ['Disconnected to serial port: ',app.SerialPortsDropDown.Value];
                    update_terminal(app,new_text);
            end

            function readSerialData(src,evt)
                app.User_Data = char(readline(app.serial_handle));
                serial_data_handler(app);
            end

        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            app.TerminalTextArea.Value = {''};
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SerialSettingsTab = uitab(app.TabGroup);
            app.SerialSettingsTab.Title = 'Serial Settings';
            app.Label = uilabel(app.SerialSettingsTab);
            app.Label.Text = 'Serial Ports';
            app.SerialPortsDropDown = uidropdown(app.SerialSettingsTab);
            app.SerialPortsDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortsDropDownOpening, true);
            app.BaudRateDropDownLabel = uilabel(app.SerialSettingsTab);
            app.BaudRateDropDownLabel.Text = 'Baud Rate';
            app.BaudRateDropDown = uidropdown(app.SerialSettingsTab);
            app.BaudRateDropDown.Items = {'9600', '11200'};
            app.BaudRateDropDown.Value = '9600';
            app.ConnectButton = uibutton(app.SerialSettingsTab, 'state');
            app.ConnectButton.ValueChangedFcn = createCallbackFcn(app, @ConnectButtonValueChanged, true);
            app.ConnectButton.Text = 'Connect';
            app.TerminalTextAreaLabel = uilabel(app.SerialSettingsTab);
            app.TerminalTextAreaLabel.Text = 'Terminal';
            app.TerminalTextArea = uitextarea(app.SerialSettingsTab);
            app.ClearButton = uibutton(app.SerialSettingsTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.SendButton = uibutton(app.SerialSettingsTab, 'push');
            app.SendButton.Text = 'Send';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = swjv0_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end